classdef MatlabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MenuTab                         matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SimulationApplicationLabel      matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        SelectanimagefromtheMRIimagedirectoryLabel  matlab.ui.control.Label
        SelectanimagefromtheCTimagedirectoryLabel  matlab.ui.control.Label
        statusSimulationLabel           matlab.ui.control.Label
        SimulateButton                  matlab.ui.control.Button
        pathoffileimrLabel              matlab.ui.control.Label
        pathoffileCTLabel               matlab.ui.control.Label
        MRIButton                       matlab.ui.control.Button
        CTButton                        matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        FocalDistancemmEditField        matlab.ui.control.EditField
        FocalDistancemmEditFieldLabel   matlab.ui.control.Label
        TissuessettingsLabel            matlab.ui.control.Label
        WatersettingsLabel              matlab.ui.control.Label
        PointperperiodEditField         matlab.ui.control.EditField
        PointperperiodEditFieldLabel    matlab.ui.control.Label
        path_configjsonLabel            matlab.ui.control.Label
        ImportconfigButton              matlab.ui.control.Button
        TransducteursettingsLabel       matlab.ui.control.Label
        SimulationsettingsLabel         matlab.ui.control.Label
        NameAnimaldefaultMacaqueEditField  matlab.ui.control.EditField
        NameAnimaldefaultMacaqueEditFieldLabel  matlab.ui.control.Label
        TransducersurfaceacousticpressurePaEditField  matlab.ui.control.EditField
        TransducersurfaceacousticpressurePaEditFieldLabel  matlab.ui.control.Label
        EffectivetransducerdiametermEditField  matlab.ui.control.EditField
        EffectivetransducerdiametermEditFieldLabel  matlab.ui.control.Label
        TransducercurvatureradiusmEditField  matlab.ui.control.EditField
        TransducercurvatureradiusmEditFieldLabel  matlab.ui.control.Label
        SpeedcorticalbonemsEditField    matlab.ui.control.EditField
        SpeedcorticalbonemsEditFieldLabel  matlab.ui.control.Label
        SofttissuedensityKgm3EditField  matlab.ui.control.EditField
        SofttissuedensityKgm3EditFieldLabel  matlab.ui.control.Label
        SpeedsofttissuemsEditField      matlab.ui.control.EditField
        SpeedsofttissuemsEditFieldLabel  matlab.ui.control.Label
        DensitywaterKgm3EditField       matlab.ui.control.EditField
        Densiteau103KgmLabel            matlab.ui.control.Label
        message_statusLabel             matlab.ui.control.Label
        saveButton                      matlab.ui.control.Button
        PointsperwavelengthEditField    matlab.ui.control.EditField
        PointsperwavelengthEditFieldLabel  matlab.ui.control.Label
        WaveFrequencyMHzEditField       matlab.ui.control.EditField
        WaveFrequencyMHzEditFieldLabel  matlab.ui.control.Label
        WaveSpeedmsEditField            matlab.ui.control.EditField
        WaveSpeedmsEditFieldLabel       matlab.ui.control.Label
        MaxbonethresholdHUEditField     matlab.ui.control.EditField
        MaxbonethresholdHUEditFieldLabel  matlab.ui.control.Label
        MinbonethresholdHUEditField     matlab.ui.control.EditField
        MinbonethresholdHUEditFieldLabel  matlab.ui.control.Label
        MaxwaterthresholdHUEditField    matlab.ui.control.EditField
        MaxwaterthresholdHUEditFieldLabel  matlab.ui.control.Label
        AdvancedsettingsLabel           matlab.ui.control.Label
        ExportdataTab                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        SavedatabeforeexportingLabel    matlab.ui.control.Label
        status_saveLabel                matlab.ui.control.Label
        ExportconfigurationButton       matlab.ui.control.Button
        ExportDataLabel                 matlab.ui.control.Label
        AboutTab                        matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        ExportdataLabel                 matlab.ui.control.Label
        SettingLabel                    matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ConfigurationLabel              matlab.ui.control.Label
        DescriptionLabel                matlab.ui.control.Label
    end

    
    properties (Access = public)
        controller        
    end  
       

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function OpeningFnc(app)
            clc
            addpath('controller\');
            addpath('view\');
            addpath('singleton\'); 
            addpath('model\')
            app.controller = Controller(app);            
            app.controller.setUpDataConfig(); 
        end

        % Button pushed function: CTButton
        function selectCtPicture(app, event)
               app.controller.openCTFile();                
        end

        % Button pushed function: MRIButton
        function selectImrFile(app, event)
                app.controller.openIRMFile();
        end

        % Button pushed function: SimulateButton
        function makeSimulation(app, event)
                         app.controller.makeSimulation();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
        delete(app.UIFigure);        
        end

        % Callback function
        function compute(app, event)

        end

        % Button pushed function: saveButton
        function saveData(app, event)
            app.controller.updateStatus(app.controller.saveData(app));
            app.controller.setUpDataConfig();
        end

        % Button pushed function: ExportconfigurationButton
        function exportData(app, event)
            app.controller.exportData();            
        end

        % Button pushed function: ImportconfigButton
        function loadConfig(app, event)
            app.controller.loadConfig();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MenuTab = uitab(app.TabGroup);
            app.MenuTab.Title = 'Menu';
            app.GridLayout = uigridlayout(app.MenuTab);
            app.CTButton = uibutton(app.GridLayout, 'push');
            app.CTButton.ButtonPushedFcn = createCallbackFcn(app, @selectCtPicture, true);
            app.CTButton.Text = 'CT';
            app.MRIButton = uibutton(app.GridLayout, 'push');
            app.MRIButton.ButtonPushedFcn = createCallbackFcn(app, @selectImrFile, true);
            app.MRIButton.Text = 'MRI';
            app.pathoffileCTLabel = uilabel(app.GridLayout);
            app.pathoffileCTLabel.Visible = 'off';
            app.pathoffileCTLabel.Text = 'path of file CT';
            app.pathoffileimrLabel = uilabel(app.GridLayout);
            app.pathoffileimrLabel.Visible = 'off';
            app.pathoffileimrLabel.Text = 'path of file imr';
            app.SimulateButton = uibutton(app.GridLayout, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @makeSimulation, true);
            app.SimulateButton.Enable = 'off';
            app.SimulateButton.Text = 'Simulate';
            app.statusSimulationLabel = uilabel(app.GridLayout);
            app.statusSimulationLabel.Visible = 'off';
            app.statusSimulationLabel.Text = 'statusSimulation';
            app.SelectanimagefromtheCTimagedirectoryLabel = uilabel(app.GridLayout);
            app.SelectanimagefromtheCTimagedirectoryLabel.Text = 'Select an image from the CT image directory :';
            app.SelectanimagefromtheMRIimagedirectoryLabel = uilabel(app.GridLayout);
            app.SelectanimagefromtheMRIimagedirectoryLabel.Text = 'Select an image from the MRI image directory :';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Logo_Imaging&Brain.jpeg');
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Sans titre.png');
            app.SimulationApplicationLabel = uilabel(app.GridLayout);
            app.SimulationApplicationLabel.Text = 'Simulation Application';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.GridLayout3 = uigridlayout(app.SettingsTab);
            app.AdvancedsettingsLabel = uilabel(app.GridLayout3);
            app.AdvancedsettingsLabel.Text = 'Advanced settings';
            app.MaxwaterthresholdHUEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxwaterthresholdHUEditFieldLabel.Text = 'Max water threshold (HU)';
            app.MaxwaterthresholdHUEditField = uieditfield(app.GridLayout3, 'text');
            app.MinbonethresholdHUEditFieldLabel = uilabel(app.GridLayout3);
            app.MinbonethresholdHUEditFieldLabel.Text = 'Min bone threshold (HU)';
            app.MinbonethresholdHUEditField = uieditfield(app.GridLayout3, 'text');
            app.MaxbonethresholdHUEditFieldLabel = uilabel(app.GridLayout3);
            app.MaxbonethresholdHUEditFieldLabel.Text = 'Max bone threshold (HU)';
            app.MaxbonethresholdHUEditField = uieditfield(app.GridLayout3, 'text');
            app.WaveSpeedmsEditFieldLabel = uilabel(app.GridLayout3);
            app.WaveSpeedmsEditFieldLabel.Text = 'Wave Speed (m/s)';
            app.WaveSpeedmsEditField = uieditfield(app.GridLayout3, 'text');
            app.WaveFrequencyMHzEditFieldLabel = uilabel(app.GridLayout3);
            app.WaveFrequencyMHzEditFieldLabel.Text = 'Wave Frequency (MHz)';
            app.WaveFrequencyMHzEditField = uieditfield(app.GridLayout3, 'text');
            app.PointsperwavelengthEditFieldLabel = uilabel(app.GridLayout3);
            app.PointsperwavelengthEditFieldLabel.Text = 'Points per wavelength';
            app.PointsperwavelengthEditField = uieditfield(app.GridLayout3, 'text');
            app.saveButton = uibutton(app.GridLayout3, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveData, true);
            app.saveButton.Text = 'save';
            app.message_statusLabel = uilabel(app.GridLayout3);
            app.message_statusLabel.Visible = 'off';
            app.message_statusLabel.Text = 'message_status';
            app.Densiteau103KgmLabel = uilabel(app.GridLayout3);
            app.Densiteau103KgmLabel.Text = 'Density water (Kg/m^3)';
            app.DensitywaterKgm3EditField = uieditfield(app.GridLayout3, 'text');
            app.SpeedsofttissuemsEditFieldLabel = uilabel(app.GridLayout3);
            app.SpeedsofttissuemsEditFieldLabel.Text = 'Speed soft tissue (m/s)';
            app.SpeedsofttissuemsEditField = uieditfield(app.GridLayout3, 'text');
            app.SofttissuedensityKgm3EditFieldLabel = uilabel(app.GridLayout3);
            app.SofttissuedensityKgm3EditFieldLabel.Text = 'Soft tissue density (Kg/m^3)';
            app.SofttissuedensityKgm3EditField = uieditfield(app.GridLayout3, 'text');
            app.SpeedcorticalbonemsEditFieldLabel = uilabel(app.GridLayout3);
            app.SpeedcorticalbonemsEditFieldLabel.Text = 'Speed cortical bone (m/s)';
            app.SpeedcorticalbonemsEditField = uieditfield(app.GridLayout3, 'text');
            app.TransducercurvatureradiusmEditFieldLabel = uilabel(app.GridLayout3);
            app.TransducercurvatureradiusmEditFieldLabel.Text = 'Transducer curvature radius (m)';
            app.TransducercurvatureradiusmEditField = uieditfield(app.GridLayout3, 'text');
            app.EffectivetransducerdiametermEditFieldLabel = uilabel(app.GridLayout3);
            app.EffectivetransducerdiametermEditFieldLabel.Text = 'Effective transducer diameter (m)';
            app.EffectivetransducerdiametermEditField = uieditfield(app.GridLayout3, 'text');
            app.TransducersurfaceacousticpressurePaEditFieldLabel = uilabel(app.GridLayout3);
            app.TransducersurfaceacousticpressurePaEditFieldLabel.Text = 'Transducer surface acoustic pressure (Pa)';
            app.TransducersurfaceacousticpressurePaEditField = uieditfield(app.GridLayout3, 'text');
            app.NameAnimaldefaultMacaqueEditFieldLabel = uilabel(app.GridLayout3);
            app.NameAnimaldefaultMacaqueEditFieldLabel.Text = 'Name Animal (default Macaque)';
            app.NameAnimaldefaultMacaqueEditField = uieditfield(app.GridLayout3, 'text');
            app.SimulationsettingsLabel = uilabel(app.GridLayout3);
            app.SimulationsettingsLabel.Text = 'Simulation settings ';
            app.TransducteursettingsLabel = uilabel(app.GridLayout3);
            app.TransducteursettingsLabel.Text = 'Transducteur settings';
            app.ImportconfigButton = uibutton(app.GridLayout3, 'push');
            app.ImportconfigButton.ButtonPushedFcn = createCallbackFcn(app, @loadConfig, true);
            app.ImportconfigButton.Text = 'Import config';
            app.path_configjsonLabel = uilabel(app.GridLayout3);
            app.path_configjsonLabel.Visible = 'off';
            app.path_configjsonLabel.Text = 'path_config.json';
            app.PointperperiodEditFieldLabel = uilabel(app.GridLayout3);
            app.PointperperiodEditFieldLabel.Text = 'Point per period';
            app.PointperperiodEditField = uieditfield(app.GridLayout3, 'text');
            app.WatersettingsLabel = uilabel(app.GridLayout3);
            app.WatersettingsLabel.Text = 'Water settings';
            app.TissuessettingsLabel = uilabel(app.GridLayout3);
            app.TissuessettingsLabel.Text = 'Tissues settings';
            app.FocalDistancemmEditFieldLabel = uilabel(app.GridLayout3);
            app.FocalDistancemmEditFieldLabel.Text = 'Focal Distance(mm)';
            app.FocalDistancemmEditField = uieditfield(app.GridLayout3, 'text');
            app.ExportdataTab = uitab(app.TabGroup);
            app.ExportdataTab.Title = 'Export data';
            app.GridLayout2 = uigridlayout(app.ExportdataTab);
            app.ExportDataLabel = uilabel(app.GridLayout2);
            app.ExportDataLabel.Text = 'Export Data ';
            app.ExportconfigurationButton = uibutton(app.GridLayout2, 'push');
            app.ExportconfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @exportData, true);
            app.ExportconfigurationButton.Text = 'Export configuration';
            app.status_saveLabel = uilabel(app.GridLayout2);
            app.status_saveLabel.Visible = 'off';
            app.status_saveLabel.Text = 'status_save';
            app.SavedatabeforeexportingLabel = uilabel(app.GridLayout2);
            app.SavedatabeforeexportingLabel.Text = 'Save data before exporting.';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.GridLayout4 = uigridlayout(app.AboutTab);
            app.DescriptionLabel = uilabel(app.GridLayout4);
            app.DescriptionLabel.Text = 'Description';
            app.ConfigurationLabel = uilabel(app.GridLayout4);
            app.ConfigurationLabel.Text = {'1. Configuration:'; 'Simulation is only possible if you have selected Tomographic and Irm images.'; 'The choice of target will be imposed during the process, as will the angle and dimensions of the volume.'; '-You will be able to choice an other point if you want, to do this just press choice point.'; 'When you have choice a point you can''tchange the plan , you need click on the figure to change the plan. '; '-To crop the matrix just move slider and close the window.'; ''};
            app.Label_2 = uilabel(app.GridLayout4);
            app.Label_2.Text = 'Application to manipulate medical imaging data for ultrasonic simulation in a heterogeneous environment.';
            app.SettingLabel = uilabel(app.GridLayout4);
            app.SettingLabel.Text = {'2. Settings:'; 'The configuration loaded from a json file will be the last configuration found.'; ''};
            app.ExportdataLabel = uilabel(app.GridLayout4);
            app.ExportdataLabel.Text = {'3. Export data:'; 'The exported data correspond to the parameters entered in the settings window '; 'and the matrix obtained at the end of the simulation, if one has taken place.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatlabApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @OpeningFnc)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end